classdef tahun_kabisat < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        MulaiButton              matlab.ui.control.Button
        INPUTTAHUNKABISATLabel   matlab.ui.control.Label
        TahunMulaiEditField      matlab.ui.control.NumericEditField
        TahunMulaiLabel          matlab.ui.control.Label
        JumlahTahunEditField     matlab.ui.control.NumericEditField
        JumlahTahunLabel         matlab.ui.control.Label
        RightPanel               matlab.ui.container.Panel
        OUTPUTTAHUNKABISATLabel  matlab.ui.control.Label
        TextArea                 matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MulaiButton
        function MulaiButtonPushed(app, event)
            n = app.JumlahTahunEditField.Value;
            i = app.TahunMulaiEditField.Value;
            if isempty(n) || isempty(i)
                app.TextArea.Value = {'Field tidak boleh kosong'};
                return;
            end

            if n <= 0 || i <= 0 
                app.TextArea.Value = {'Input tidak boleh 0 atau < 0'};
                return;
            end

            hasil = {};

            for j = 1:n
                k = i + j - 1;
                if mod(k,4) == 0
                    if mod(k,100) == 0
                        if mod(k,400) == 0
                            hasil{end+1} = "tahun " + string(k) + " leap year";
                        else
                            hasil{end+1} = "tahun " + string(k) + " bukan leap year";
                        end
                    else
                        hasil{end+1} = "tahun " + string(k) + " leap year";
                    end
                else
                    hasil{end+1} = "tahun " + string(k) + " bukan leap year";
                end
            end

            app.TextArea.Value = string(hasil(:));
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {327, 327};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {325, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.JumlahTahunLabel = uilabel(app.LeftPanel);
            app.JumlahTahunLabel.Text = 'Jumlah Tahun :';
            app.JumlahTahunEditField = uieditfield(app.LeftPanel, 'numeric');
            app.JumlahTahunEditField.AllowEmpty = 'on';
            app.JumlahTahunEditField.Placeholder = 'Masukan Jumlah tahun';
            app.JumlahTahunEditField.Value = [];
            app.TahunMulaiLabel = uilabel(app.LeftPanel);
            app.TahunMulaiLabel.Text = 'Tahun Mulai :';
            app.TahunMulaiEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TahunMulaiEditField.AllowEmpty = 'on';
            app.TahunMulaiEditField.Placeholder = 'Masukan Tahun Mulai';
            app.TahunMulaiEditField.Value = [];
            app.INPUTTAHUNKABISATLabel = uilabel(app.LeftPanel);
            app.INPUTTAHUNKABISATLabel.Text = 'INPUT TAHUN KABISAT ';
            app.MulaiButton = uibutton(app.LeftPanel, 'push');
            app.MulaiButton.ButtonPushedFcn = createCallbackFcn(app, @MulaiButtonPushed, true);
            app.MulaiButton.Text = 'Mulai';
            app.RightPanel = uipanel(app.GridLayout);
            app.TextArea = uitextarea(app.RightPanel);
            app.OUTPUTTAHUNKABISATLabel = uilabel(app.RightPanel);
            app.OUTPUTTAHUNKABISATLabel.Text = 'OUTPUT TAHUN KABISAT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tahun_kabisat

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end